classdef EEG_Result_Viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        RightPanel             matlab.ui.container.Panel
        ExtractResultsButton   matlab.ui.control.Button
        BrowseButton           matlab.ui.control.Button
        DesignbyArikNoimanOrenMordechayLabel  matlab.ui.control.Label
        WindowSize             matlab.ui.control.Label
        Channels               matlab.ui.control.Label
        FileName               matlab.ui.control.Label
        EEGResultViewerLabel   matlab.ui.control.Label
        SummaryLabel           matlab.ui.control.Label
        Summarytext            matlab.ui.control.Label
        ClassifierButtonGroup  matlab.ui.container.ButtonGroup
        XGBButton              matlab.ui.control.RadioButton
        RFButton               matlab.ui.control.RadioButton
        LDAButton              matlab.ui.control.RadioButton
        KNNButton              matlab.ui.control.RadioButton
        SVMButton              matlab.ui.control.RadioButton
        barPlot                matlab.ui.control.UIAxes
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mat_file
        Results % Description
        Accuracy
        Precision
        Recall
        True_values
        Data
    end
    
    methods (Access = private)
        
        function display_results_func(app)
            class = app.ClassifierButtonGroup.SelectedObject.Text;
            if class == "SVM"
            app.Data = app.Results(:, 2)';
            elseif class == "KNN"
            app.Data = app.Results(:, 3)';
            elseif class == "LDA"
            app.Data = app.Results(:, 4)';
            elseif class == "RF"  % Added for Random Forest
            app.Data = app.Results(:, 5)';
            elseif class == "XGB"  % Added for XGBoost
            app.Data = app.Results(:, 6)';
            end           
            
            plot(app.UIAxes,app.True_values)
            hold( app.UIAxes, 'on')
            plot(app.UIAxes,app.Data)
            hold( app.UIAxes, 'off')
            legend(app.UIAxes,"True value",app.ClassifierButtonGroup.SelectedObject.Text,'Location','NorthEastOutside')
            xlim(app.UIAxes,[0 length(app.Results(:,1))]);
            xlabel(app.UIAxes,"Samples/Window size")
            ylabel(app.UIAxes,"Seizures")
            title(app.UIAxes,"Time line view")
            hold( app.UIAxes, 'off')
        end
        
        function results = getfilebrowse(app)
            file_name = uigetfile('C:\Users\abc\Desktop\System_for_analyzing_subjective_EEG\code\results');
            results = sprintf("%s/%s",'results',file_name);
        end
        
        function load_file(app)
            channels = ["FP1","FP2","F3",...
                        "F4","T7","T8",...
                        "F7","F8"];
            load(app.mat_file,'accuracy','precision','recall','result');
            names = split(app.mat_file,'\');
            names2 = split(names(end),'_');
            window_size = cell2mat(names2(2));
            chanels_dec = split(names2(3),'.');
            %bins = de2bi(str2num(cell2mat(chanels_dec(1))),'left-msb');
            bins = dec2bin(str2num(cell2mat(chanels_dec(1))), length(channels)) - '0';
            if length(bins) ~= length(channels)
                bins = [zeros(1,length(channels)-length(bins)) bins];
            end
            channels_names = channels(logical(bins));
            app.FileName.Text = sprintf("File Path : %s",app.mat_file);
            app.WindowSize.Text = sprintf("Window size : %s",window_size);
            app.Channels.Text = "Channels :" + sprintf("%s,",channels_names);
            app.Results = result;
            app.Accuracy = accuracy;
            app.Precision = precision;
            app.Recall = recall;
            app.True_values =  app.Results(:,1)';
            bar(app.barPlot,([app.Accuracy app.Precision app.Recall]))
            xticklabels(app.barPlot,{'SVM','KNN','LDA','RF','XGB'});
            xlabel(app.barPlot,'')
            ylabel(app.barPlot,'')
            title(app.barPlot,'Result')
            legend(app.barPlot,"Accuracy","Precision","Recall",'Location','NorthEastOutside')
            text = sprintf("SVM:\n Accuracy = %.2f%%\n Precision = %.2f%%\n Recall = %.2f%%\n",100*app.Accuracy(1),100*app.Precision(1),100*app.Recall(1)); 
            text = sprintf("%sKNN:\n Accuracy = %.2f%%\n Precision = %.2f%%\n Recall = %.2f%%\n",text,100*app.Accuracy(2),100*app.Precision(2),100*app.Recall(2)); 
            text = sprintf("%sLDA:\n Accuracy = %.2f%%\n Precision = %.2f%%\n Recall = %.2f%%\n",text,100*app.Accuracy(3),100*app.Precision(3),100*app.Recall(3));
            text = sprintf("%sRF:\n Accuracy = %.2f%%\n Precision = %.2f%%,\n Recall = %.2f%%\n",text,100*app.Accuracy(4),100*app.Precision(4),100*app.Recall(4)); 
            text = sprintf("%sXGB:\n Accuracy = %.2f%%\n Precision = %.2f%%,\n Recall = %.2f%%",text,100*app.Accuracy(5),100*app.Precision(5),100*app.Recall(5));
            
            app.Summarytext.Text = text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mat_file)
            if ~exist('mat_file','var')
                mat_file = getfilebrowse(app);
            end
            app.mat_file = mat_file;
            app.load_file()
            app.display_results_func()

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {615, 615};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function
        function ClassifierButtonGroupSelectionChanged(app, event)
            app.display_results_func()
        end

        % Selection changed function: ClassifierButtonGroup
        function ClassifierButtonGroupSelectionChanged2(app, event)
            selectedButton = app.ClassifierButtonGroup.SelectedObject;
            app.display_results_func()
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.mat_file = getfilebrowse(app); 
            app.load_file()
            app.display_results_func()
        end

        % Button pushed function: ExtractResultsButton
        function ExtractResultsButtonPushed(app, event)
            [~,name,~] = fileparts(app.mat_file);
            var_name = sprintf('%s_Results',name);
            set_val = {app.Accuracy,app.Precision,app.Recall,app.Results};
            set_key = {'Accuracy','Precision','Recall','Results'};
            val = containers.Map(set_key,set_val,'UniformValues',false);
            assignin('base',var_name,val);
            msgbox(sprintf('Results extract to workspace: %s',var_name),'Finish')
        end

        % Size changed function: ClassifierButtonGroup
        function ClassifierButtonGroupSizeChanged(app, event)
            position = app.ClassifierButtonGroup.Position;
            
        end

        % Button down function: ClassifierButtonGroup
        function ClassifierButtonGroupButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.barPlot = uiaxes(app.RightPanel);
            title(app.barPlot, 'Title')
            xlabel(app.barPlot, 'X')
            ylabel(app.barPlot, 'Y')
            app.ClassifierButtonGroup = uibuttongroup(app.RightPanel);
            app.ClassifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ClassifierButtonGroupSelectionChanged2, true);
            app.ClassifierButtonGroup.Title = 'Classifier';
            app.ClassifierButtonGroup.SizeChangedFcn = createCallbackFcn(app, @ClassifierButtonGroupSizeChanged, true);
            app.ClassifierButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ClassifierButtonGroupButtonDown, true);
            app.SVMButton = uiradiobutton(app.ClassifierButtonGroup);
            app.SVMButton.Text = 'SVM';
            app.SVMButton.Value = true;
            app.KNNButton = uiradiobutton(app.ClassifierButtonGroup);
            app.KNNButton.Text = 'KNN';
            app.LDAButton = uiradiobutton(app.ClassifierButtonGroup);
            app.LDAButton.Text = 'LDA';
            app.RFButton = uiradiobutton(app.ClassifierButtonGroup);
            app.RFButton.Text = 'RF';
            app.XGBButton = uiradiobutton(app.ClassifierButtonGroup);
            app.XGBButton.Text = 'XGB';
            app.Summarytext = uilabel(app.RightPanel);
            app.Summarytext.Text = 'SummaryText';
            app.SummaryLabel = uilabel(app.RightPanel);
            app.SummaryLabel.Text = 'Summary:';
            app.EEGResultViewerLabel = uilabel(app.RightPanel);
            app.EEGResultViewerLabel.Text = 'EEG Result Viewer';
            app.FileName = uilabel(app.RightPanel);
            app.Channels = uilabel(app.RightPanel);
            app.WindowSize = uilabel(app.RightPanel);
            app.WindowSize.Text = 'Label2';
            app.DesignbyArikNoimanOrenMordechayLabel = uilabel(app.RightPanel);
            app.DesignbyArikNoimanOrenMordechayLabel.Text = 'Design by: Arik Noiman & Oren Mordechay';
            app.BrowseButton = uibutton(app.RightPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ExtractResultsButton = uibutton(app.RightPanel, 'push');
            app.ExtractResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExtractResultsButtonPushed, true);
            app.ExtractResultsButton.Text = 'Extract  Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Result_Viewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end